classdef SegmentLab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        SegmentarButton               matlab.ui.control.Button
        ApellidodelpacienteLabel      matlab.ui.control.Label
        ApellidodelpacienteEditField  matlab.ui.control.EditField
        CargarEstudioButton           matlab.ui.control.Button
        MomentodelcicloDropDownLabel  matlab.ui.control.Label
        MomentodelcicloDropDown       matlab.ui.control.DropDown
        GuardarContornoButton         matlab.ui.control.Button
    end


    properties (Access = public)
        Apellido % Description
        Study % Description
      
    end

    properties (Access = public)
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(gcf, 'units','normalized','outerposition',[0 0 1 1]);
        end

        % Button pushed function: SegmentarButton
        function SegmentarButtonPushed(app, event)
            if(~isempty(app.Study))
                figure(1)
                imshow(app.Study)
                H = imfreehand('Closed',1);
                p = wait(H)
                
                px = p(:,1);
                py = p(:,2);
                
                T= table([strcat(app.Apellido,'_x'),strcat(app.Apellido,'_y')],px,py);
                save(strcat('placa_',app.Apellido,'.mat') , 'p' )
                
            else 
                f = errordlg('No hay ningun estudio cargado','ERROR');
            end
        end

        % Button pushed function: CargarEstudioButton
        function CargarEstudioButtonPushed(app, event)

            if(~isempty(app.Apellido))
                app.Study = Read_image_and_apply_LUT(strcat(app.Apellido,'.dcm'));
                app.Study = app.Study(:,:,:,50); 
                app.Study = app.Study(71:522,267:654);
                axis(app.UIAxes,'off')
                imshow(app.Study,'Parent',app.UIAxes)
            else 
                f = errordlg('Elija un paciente por su apellido','ERROR');
            end

           
        end

        % Value changing function: ApellidodelpacienteEditField
        function ApellidodelpacienteEditFieldValueChanging(app, event)
            app.Apellido = event.Value;
        end

        % Button pushed function: GuardarContornoButton
        function GuardarContornoButtonPushed(app, event)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.SegmentarButton = uibutton(app.UIFigure, 'push');
            app.SegmentarButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentarButtonPushed, true);
            app.SegmentarButton.Text = 'Segmentar';
            app.ApellidodelpacienteLabel = uilabel(app.UIFigure);
            app.ApellidodelpacienteLabel.Text = {'Apellido del'; 'paciente'};
            app.ApellidodelpacienteEditField = uieditfield(app.UIFigure, 'text');
            app.ApellidodelpacienteEditField.ValueChangingFcn = createCallbackFcn(app, @ApellidodelpacienteEditFieldValueChanging, true);
            app.CargarEstudioButton = uibutton(app.UIFigure, 'push');
            app.CargarEstudioButton.ButtonPushedFcn = createCallbackFcn(app, @CargarEstudioButtonPushed, true);
            app.CargarEstudioButton.Text = 'Cargar Estudio';
            app.MomentodelcicloDropDownLabel = uilabel(app.UIFigure);
            app.MomentodelcicloDropDownLabel.Text = {'Momento '; 'del ciclo'};
            app.MomentodelcicloDropDown = uidropdown(app.UIFigure);
            app.MomentodelcicloDropDown.Items = {'Sístole', 'Diástole'};
            app.MomentodelcicloDropDown.Value = 'Sístole';
            app.GuardarContornoButton = uibutton(app.UIFigure, 'push');
            app.GuardarContornoButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarContornoButtonPushed, true);
            app.GuardarContornoButton.Text = 'Guardar Contorno';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SegmentLab

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end